classdef FinalProjectGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Button_Record    matlab.ui.control.Button
        Button_Finish    matlab.ui.control.Button
        Button_Play      matlab.ui.control.Button
        Button_ReRecord  matlab.ui.control.Button
        UITable          matlab.ui.control.Table
        Button_Analysis  matlab.ui.control.Button
        Label            matlab.ui.control.Label
        ListBox          matlab.ui.control.ListBox
        UIAxes           matlab.ui.control.UIAxes
        Button_Delete    matlab.ui.control.Button
        Label_2          matlab.ui.control.Label
        Button_Upload    matlab.ui.control.Button
        Button_Exit      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_Exit
        function Button_ExitPushed(app, event)
            ex = questdlg("你確定要親手終結這個應用程式？",'提示視窗','是','否','否');
            if ex=='是'
                   delete(app)
            end
        end

        % Button pushed function: Button_Record
        function Button_RecordPushed(app, event)
            fs = 48000;  % 采样频率
            length = 4; % 时间长度(秒） 
            % 创建一个录音文件：fs =48000Hz, 16-bit, 单通道
            recorderObj = audiorecorder(fs, 16, 2);   
            recordblocking(recorderObj, length); % 录音4秒钟
            stop(recorderObj);
            y = getaudiodata(recorderObj);
            ymax = max(abs(y)); % 归一化
            y = y/ymax;
            time = datestr(now,'yyyymmddTHHMMSS');
            str = ['C:\Users\Jingwen\Desktop\FinalProject\',time];
            filename = [str,'.wav'];
            audiowrite(filename, y, fs); % 存储录音文件

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button_Record = uibutton(app.UIFigure, 'push');
            app.Button_Record.ButtonPushedFcn = createCallbackFcn(app, @Button_RecordPushed, true);
            app.Button_Record.Text = '錄製';
            app.Button_Finish = uibutton(app.UIFigure, 'push');
            app.Button_Finish.Text = '錄製完成';
            app.Button_Play = uibutton(app.UIFigure, 'push');
            app.Button_Play.Text = '播放';
            app.Button_ReRecord = uibutton(app.UIFigure, 'push');
            app.Button_ReRecord.Text = '重錄';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'歌曲名稱'; '歌手'; '指標一'; '指標二'; '指標三'; '指標四'; '指標五'};
            app.UITable.RowName = {};
            app.Button_Analysis = uibutton(app.UIFigure, 'push');
            app.Button_Analysis.Text = '開始分析';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '錄好歌曲';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'錄音檔1', '錄音檔2', '錄音檔3', '錄音檔4'};
            app.ListBox.Value = '錄音檔1';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Button_Delete = uibutton(app.UIFigure, 'push');
            app.Button_Delete.Text = '刪除';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '歌曲近似指標介面';
            app.Button_Upload = uibutton(app.UIFigure, 'push');
            app.Button_Upload.Text = '上傳';
            app.Button_Exit = uibutton(app.UIFigure, 'push');
            app.Button_Exit.ButtonPushedFcn = createCallbackFcn(app, @Button_ExitPushed, true);
            app.Button_Exit.Text = '結束程式';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalProjectGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end